classdef GUIapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DronesteeringUIFigure     matlab.ui.Figure
        Label                     matlab.ui.control.Label
        PlotresultsButton         matlab.ui.control.Button
        AutoflightButton          matlab.ui.control.Button
        DisconnectButton          matlab.ui.control.Button
        LandButton                matlab.ui.control.Button
        TakeoffButton             matlab.ui.control.Button
        DroneconnectionLamp       matlab.ui.control.Lamp
        DroneconnectionLampLabel  matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            setenv('ROS_MASTER_URI','http://192.168.1.9:11311')
            setenv('ROS_IP','192.168.1.8')
            rosinit
            initialise_all()
            app.DroneconnectionLamp.Color = 'g';
        end

        % Button pushed function: TakeoffButton
        function TakeoffButtonPushed(app, event)
            bebop_takeoff()
        end

        % Button pushed function: LandButton
        function LandButtonPushed(app, event)
            bebop_land()
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            disp('Shutting down...')
            rosshutdown
            app.DroneconnectionLamp.Color = 'r';
        end

        % Button pushed function: AutoflightButton
        function AutoflightButtonPushed(app, event)
            set_mission()
        end

        % Button pushed function: PlotresultsButton
        function PlotresultsButtonPushed(app, event)
            plot_data()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DronesteeringUIFigure = uifigure('Visible', 'off');
            app.DronesteeringUIFigure.Name = 'Drone steering';
            app.ConnectButton = uibutton(app.DronesteeringUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DroneconnectionLampLabel = uilabel(app.DronesteeringUIFigure);
            app.DroneconnectionLampLabel.Text = 'Drone connection';
            app.DroneconnectionLamp = uilamp(app.DronesteeringUIFigure);
            app.TakeoffButton = uibutton(app.DronesteeringUIFigure, 'push');
            app.TakeoffButton.ButtonPushedFcn = createCallbackFcn(app, @TakeoffButtonPushed, true);
            app.TakeoffButton.Text = 'Takeoff';
            app.LandButton = uibutton(app.DronesteeringUIFigure, 'push');
            app.LandButton.ButtonPushedFcn = createCallbackFcn(app, @LandButtonPushed, true);
            app.LandButton.Text = 'Land';
            app.DisconnectButton = uibutton(app.DronesteeringUIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.AutoflightButton = uibutton(app.DronesteeringUIFigure, 'push');
            app.AutoflightButton.ButtonPushedFcn = createCallbackFcn(app, @AutoflightButtonPushed, true);
            app.AutoflightButton.Text = 'Autoflight';
            app.PlotresultsButton = uibutton(app.DronesteeringUIFigure, 'push');
            app.PlotresultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotresultsButtonPushed, true);
            app.PlotresultsButton.Text = 'Plot results';
            app.Label = uilabel(app.DronesteeringUIFigure);
            app.Label.Text = {'Jeremiasz Szczecina'; 'Michał Działakiewicz'; 'Politechnika Poznańska'; 'AiR WARiE, s. VI, 2021'; 'Projekt przejściowy'};
            % Show the figure after all components are created
            app.DronesteeringUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DronesteeringUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DronesteeringUIFigure)
        end
    end
end